classdef avelasco_criptografiaapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        btn_exit                        matlab.ui.control.Button
        SimuladordeTransmisindeMensajeCifradoLabel  matlab.ui.control.Label
        GeneradordeClaveRSALabel        matlab.ui.control.Label
        IniciodeTransmisinPanel         matlab.ui.container.Panel
        btn_tx                          matlab.ui.control.Button
        txt_msg                         matlab.ui.control.EditField
        MensajeEditFieldLabel           matlab.ui.control.Label
        ProcesodeTransmisinPanel        matlab.ui.container.Panel
        txt_descifrado                  matlab.ui.control.Label
        txt_cifrado                     matlab.ui.control.Label
        lmp_fin                         matlab.ui.control.Lamp
        lmp_dcifra                      matlab.ui.control.Lamp
        lmp_cifra                       matlab.ui.control.Lamp
        lmp_ascii                       matlab.ui.control.Lamp
        txt_final                       matlab.ui.control.Label
        lbl_N_4                         matlab.ui.control.Label
        Image2_3                        matlab.ui.control.Image
        Image2_2                        matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        txa_descifrar                   matlab.ui.control.TextArea
        DescifradoLabel                 matlab.ui.control.Label
        lbl_N_2                         matlab.ui.control.Label
        txt_plt                         matlab.ui.control.Label
        txa_cifrar                      matlab.ui.control.TextArea
        CifradoLabel                    matlab.ui.control.Label
        ClavesRSAPanel                  matlab.ui.container.Panel
        txa_pub                         matlab.ui.control.TextArea
        ClavePblicaenLabel              matlab.ui.control.Label
        txa_priv                        matlab.ui.control.TextArea
        ClavePrivadadnTextAreaLabel     matlab.ui.control.Label
        ComponentesRSAPanel             matlab.ui.container.Panel
        txt_phi                         matlab.ui.control.Label
        txt_N                           matlab.ui.control.Label
        lbl_phi                         matlab.ui.control.Label
        lbl_N                           matlab.ui.control.Label
        TiempodeBitEditFieldLabel_19    matlab.ui.control.Label
        SeguridadenRedesLabel           matlab.ui.control.Label
        lmp_gen                         matlab.ui.control.Lamp
        btn_generar                     matlab.ui.control.Button
        ParmetrospyqPanel               matlab.ui.container.Panel
        lbl_q                           matlab.ui.control.Label
        txt_q                           matlab.ui.control.NumericEditField
        lbl_p                           matlab.ui.control.Label
        txt_p                           matlab.ui.control.NumericEditField
        FICACIERCOMLabel                matlab.ui.control.Label
        UniversidadTcnicadelNorteLabel  matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end


%     properties (Access = public)
%         Property2 % Description
%         N;
%         publica;
%         privada;
%     end
%     
%     methods (Access = public)
%         
%         function startupFcn(app)
%             app.N;
%             publica;
%             privada;
%             
%         end
%     end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function chbModoValueChanged(app, event)
            
        end

        % Callback function
        function btnBitsValueChanged(app, event)
            
        end

        % Button pushed function: btn_generar
        function btn_generarButtonPushed(app, event)
           
            try
                app.txa_priv.Value = ' ';
                app.txa_pub.Value = ' ';
                p = app.txt_p.Value;
                q = app.txt_q.Value;
                
                %ES PRIMO?
                if isprime(p)==0||isprime(q)==0
                    f = warndlg('Los prarámetros p y q deben ser Números Primos','Datos Incorrectos');
                    app.lmp_gen.Color='1,0,0';
                elseif p<=3||p<=3
                    f = warndlg('Los prarámetros p y q deben ser mayores a 3','Datos Incorrectos');
                    app.lmp_gen.Color='1,0,0';
                else
                    
                global N publica privada Fi
                N=p*q %Hallar el producto N=p×q
                app.txt_N.Text=num2str(N);
                Fi=(p-1)*(q-1) %Calcular la función indicador de Euler
                app.txt_phi.Text=num2str(Fi);
                
                
                %CLAVE PÚBLICA
                %Calcular un entero e primo menor que Fi(N) / mcd(e, Fi(N))=1
                for i=2:Fi
                    if gcd(i,Fi)==1 %Comprobación si el módulo es 1
                        break;
                    end
                end
                
                publica=i; %Clave pública es el valor donde se cumple la igualdad.
                clave = ['{%d,%d}'];
                app.txa_pub.Value=sprintf(clave,publica,N);
                
                %CLAVE PRIVADA
                a=publica;
                b=Fi;
                x=[1 0]; %Valor constante de x
                y=[0 1]; %Valor constante de y
                q=0; r=0; m=0; n=0; %Inicialización de variables
                while a~=0 %Lazo hasta encontrar el inverso
                    q=fix(b/a); %Redondear la división
                    r=mod(b,a); %Módulo de los números b y a
                    m=x(2)-q*x(1); %Ecuaciones de Euclides
                    n=y(2)-q*y(1); %Ecuaciones de Euclides
                    x(2)=x(1); %Almacenar temporalmente el valor de x(1)
                    y(2)=y(1); %Almacenar temporalmente el valor de y(1)
                    x(1)=m; y(1)=n; %Almacenar temporalmente el valor de m y n
                    b=a; a=r; %Los nuevos b y a serán a y r respectivamente
                end
                if x(2)>0 %Si el valor obtenido es mayor a 0
                    privada=x(2); %El inverso es x(2)
                else %Si el valor obtenido es menor a 0
                    privada=x(1)+x(2); %El inverso es x(1)+x(2)
                end
                app.txa_priv.Value=sprintf(clave,privada,N);
                
                app.txt_msg.Enable=true;
                app.btn_tx.Enable=true;
                app.lmp_gen.Color='0,1,0';
                end
            catch
                app.lmp_gen.Color='1,0,0';
            end
        end

        % Callback function
        function chbRandValueChanged(app, event)
            
        end

        % Callback function
        function btnIniciar_2ButtonPushed(app, event)
            
        end

        % Button pushed function: btn_tx
        function btn_txButtonPushed(app, event)
           try
                app.txa_cifrar.Value = ' ';
                app.txa_descifrar.Value = ' ';
                msg=app.txt_msg.Value;
                global N privada publica
                app.lmp_ascii.Color='1,0,0';
                l=length(msg) %Conocer la longitud del mensaje
                c=double(msg); %Convertir a decimal los valores de la clave
                
                app.txt_plt.Text= num2str(c);
                app.lmp_ascii.Color='0,1,0';
                
                
                formato = ['--------------------------------\n' ...
                           '     \n' ...
                           '        %d\n' ...
                           '   %d  mod %d = %d\n' ...
                           '     \n' ...
                           '--------------------------------\n'];
                
                %Proceso de cifrado
                powc=sym(c.^publica);
                app.lmp_cifra.Color='1,0,0';
                for j = 1:l
                    cifra(j)=powc(j)-sym(N*fix(sym(powc(j)/N))); %C=M^e mod (N)
                    %cifra(j)=mod(c(j)^publica,N); %C=M^e mod (N)
                end
                e=double(cifra)
                
                %app.txa_cifrar.Value = sprintf(formato,publica,c,Fi,e);
                
                for k = 1 : l
                    relleno = sprintf(formato,publica,c(k),N,e(k));
                    if strcmp(app.txa_cifrar.Value,' ')
                        bkp = relleno;
                    else
                        bkp = [app.txa_cifrar.Value(:)', {relleno}];
                    end
                    app.txa_cifrar.Value = bkp;
                end
                
                app.txt_cifrado.Text = num2str(e);
                app.lmp_cifra.Color='0,1,0';
                
                %Proceso de descifrado
                app.lmp_dcifra.Color='1,0,0';
                powd = sym(cifra.^privada);
                for j= 1:l
                    %dcifra(j)=mod(sym(cifra(j)^privada),N) %M=C^d mod (N)
                    dcifra(j)= powd(j)-sym(N*fix(sym(powd(j)/N)));  %M=(C^d) mod (N)
                    
                end
                d=double(dcifra);
                %app.txa_descifrar.Value = sprintf(formato,privada,e,N,d);
                
                for k = 1 : l
                    relleno = sprintf(formato,privada,e(k),N,d(k))
                    if strcmp(app.txa_descifrar.Value,' ')
                        bkp = relleno;
                    else
                        bkp = [app.txa_descifrar.Value(:)', {relleno}];
                    end
                    app.txa_descifrar.Value = bkp;
                end
                
                app.txt_descifrado.Text = num2str(d);
                
                app.lmp_dcifra.Color='0,1,0';
                
                app.lmp_fin.Color='1,0,0';
                f=char(d);
                app.txt_final.Text = num2str(f);
                app.lmp_fin.Color='0,1,0';
                
            catch ME
                
            end
        end

        % Button pushed function: btn_exit
        function btn_exitButtonPushed(app, event)
            close(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UniversidadTcnicadelNorteLabel = uilabel(app.UIFigure);
            app.UniversidadTcnicadelNorteLabel.Text = {'Universidad Técnica del Norte'; ''};
            app.FICACIERCOMLabel = uilabel(app.UIFigure);
            app.FICACIERCOMLabel.Text = 'FICA - CIERCOM';
            app.ParmetrospyqPanel = uipanel(app.UIFigure);
            app.ParmetrospyqPanel.Title = 'Parámetros p y q';
            app.txt_p = uieditfield(app.ParmetrospyqPanel, 'numeric');
            app.txt_p.Value = 13;
            app.lbl_p = uilabel(app.ParmetrospyqPanel);
            app.lbl_p.Text = 'Número p';
            app.txt_q = uieditfield(app.ParmetrospyqPanel, 'numeric');
            app.txt_q.Value = 11;
            app.lbl_q = uilabel(app.ParmetrospyqPanel);
            app.lbl_q.Text = 'Número q';
            app.btn_generar = uibutton(app.UIFigure, 'push');
            app.btn_generar.ButtonPushedFcn = createCallbackFcn(app, @btn_generarButtonPushed, true);
            app.btn_generar.Text = 'GENERAR CLAVES RSA';
            app.lmp_gen = uilamp(app.UIFigure);
            app.SeguridadenRedesLabel = uilabel(app.UIFigure);
            app.SeguridadenRedesLabel.Text = 'Seguridad en Redes';
            app.TiempodeBitEditFieldLabel_19 = uilabel(app.UIFigure);
            app.TiempodeBitEditFieldLabel_19.Text = 'AUTOR: Angel Velasco';
            app.ComponentesRSAPanel = uipanel(app.UIFigure);
            app.ComponentesRSAPanel.Title = 'Componentes RSA';
            app.lbl_N = uilabel(app.ComponentesRSAPanel);
            app.lbl_N.Text = 'N';
            app.lbl_phi = uilabel(app.ComponentesRSAPanel);
            app.lbl_phi.Text = 'Φ(n)';
            app.txt_N = uilabel(app.ComponentesRSAPanel);
            app.txt_N.Text = '';
            app.txt_phi = uilabel(app.ComponentesRSAPanel);
            app.txt_phi.Text = '';
            app.ClavesRSAPanel = uipanel(app.UIFigure);
            app.ClavesRSAPanel.Title = 'Claves RSA';
            app.ClavePrivadadnTextAreaLabel = uilabel(app.ClavesRSAPanel);
            app.ClavePrivadadnTextAreaLabel.Text = 'Clave Privada {d,n}';
            app.txa_priv = uitextarea(app.ClavesRSAPanel);
            app.ClavePblicaenLabel = uilabel(app.ClavesRSAPanel);
            app.ClavePblicaenLabel.Text = 'Clave Pública {e,n}';
            app.txa_pub = uitextarea(app.ClavesRSAPanel);
            app.ProcesodeTransmisinPanel = uipanel(app.UIFigure);
            app.ProcesodeTransmisinPanel.Title = 'Proceso de Transmisión';
            app.CifradoLabel = uilabel(app.ProcesodeTransmisinPanel);
            app.CifradoLabel.Text = 'Cifrado';
            app.txa_cifrar = uitextarea(app.ProcesodeTransmisinPanel);
            app.txt_plt = uilabel(app.ProcesodeTransmisinPanel);
            app.txt_plt.Text = '';
            app.lbl_N_2 = uilabel(app.ProcesodeTransmisinPanel);
            app.lbl_N_2.Text = 'Texto plano (ASCII)';
            app.DescifradoLabel = uilabel(app.ProcesodeTransmisinPanel);
            app.DescifradoLabel.Text = 'Descifrado';
            app.txa_descifrar = uitextarea(app.ProcesodeTransmisinPanel);
            app.Image2 = uiimage(app.ProcesodeTransmisinPanel);
            app.Image2.Enable = 'off';
            app.Image2.ImageSource = 'arrow.png';
            app.Image2_2 = uiimage(app.ProcesodeTransmisinPanel);
            app.Image2_2.Enable = 'off';
            app.Image2_2.ImageSource = 'arrow.png';
            app.Image2_3 = uiimage(app.ProcesodeTransmisinPanel);
            app.Image2_3.Enable = 'off';
            app.Image2_3.ImageSource = 'arrow.png';
            app.lbl_N_4 = uilabel(app.ProcesodeTransmisinPanel);
            app.lbl_N_4.Text = 'Mensaje Descifrado';
            app.txt_final = uilabel(app.ProcesodeTransmisinPanel);
            app.txt_final.Text = '';
            app.lmp_ascii = uilamp(app.ProcesodeTransmisinPanel);
            app.lmp_cifra = uilamp(app.ProcesodeTransmisinPanel);
            app.lmp_dcifra = uilamp(app.ProcesodeTransmisinPanel);
            app.lmp_fin = uilamp(app.ProcesodeTransmisinPanel);
            app.txt_cifrado = uilabel(app.ProcesodeTransmisinPanel);
            app.txt_cifrado.Text = '';
            app.txt_descifrado = uilabel(app.ProcesodeTransmisinPanel);
            app.txt_descifrado.Text = '';
            app.IniciodeTransmisinPanel = uipanel(app.UIFigure);
            app.IniciodeTransmisinPanel.Title = 'Inicio de Transmisión';
            app.MensajeEditFieldLabel = uilabel(app.IniciodeTransmisinPanel);
            app.MensajeEditFieldLabel.Enable = 'off';
            app.MensajeEditFieldLabel.Text = 'Mensaje';
            app.txt_msg = uieditfield(app.IniciodeTransmisinPanel, 'text');
            app.txt_msg.Enable = 'off';
            app.txt_msg.Value = 'hello world';
            app.btn_tx = uibutton(app.IniciodeTransmisinPanel, 'push');
            app.btn_tx.ButtonPushedFcn = createCallbackFcn(app, @btn_txButtonPushed, true);
            app.btn_tx.Enable = 'off';
            app.btn_tx.Text = 'TRANSMITIR';
            app.GeneradordeClaveRSALabel = uilabel(app.UIFigure);
            app.GeneradordeClaveRSALabel.Text = 'Generador de Clave RSA';
            app.SimuladordeTransmisindeMensajeCifradoLabel = uilabel(app.UIFigure);
            app.SimuladordeTransmisindeMensajeCifradoLabel.Text = 'Simulador de Transmisión de Mensaje Cifrado';
            app.btn_exit = uibutton(app.UIFigure, 'push');
            app.btn_exit.ButtonPushedFcn = createCallbackFcn(app, @btn_exitButtonPushed, true);
            app.btn_exit.Text = 'CERRAR';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = avelasco_criptografiaapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end